classdef lsAudioTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        laSalleEquaiozerUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenfileMenu              matlab.ui.container.Menu
        SavefileMenu              matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        RTlabel                   matlab.ui.control.Label
        BufferLengthSlider        matlab.ui.control.Slider
        BufferLengthSliderLabel   matlab.ui.control.Label
        ControlPanel              matlab.ui.container.Panel
        onoff                     matlab.ui.control.Switch
        Origi_Filt                matlab.ui.control.Switch
        Freq_Time                 matlab.ui.control.Switch
        ActiveEffectsLabel        matlab.ui.control.Label
        EffectDropDown            matlab.ui.control.DropDown
        Reverb                    matlab.ui.container.Panel
        MixKnob                   matlab.ui.control.Knob
        ReverbTimeKnob            matlab.ui.control.Knob
        MixLabel                  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ReverbLabel               matlab.ui.control.Label
        Equalizer                 matlab.ui.container.Panel
        HPFGaindBKnob             matlab.ui.control.Knob
        BPFGaindBKnob             matlab.ui.control.Knob
        LPFGaindBKnob             matlab.ui.control.Knob
        HPFGaindBKnobLabel        matlab.ui.control.Label
        BPFGaindBKnobLabel        matlab.ui.control.Label
        LPFGaindBKnobLabel        matlab.ui.control.Label
        EqualizerLabel            matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
        UI2Axes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        deviceReader %objecte que extreu les trames d'àudio del conversor A/D.
        deviceWriter % objecte que envia la trama d'àudio amb efecte cap al conversorD/A.
        fs % freqüència de mostratge a la que han de treballar els conversors A/D i D/A (valor 44100 Hz).
        audio % vector que conté una trama d'àudio capturada pel conversor A/D.
        audioEfect % vector que conté una trama d'àudio processada amb l efecte corresponent per a enviar al conversor D/A.
        audiof % vector que conté l'àudio d'entrada o amb efecte en el domini de la freqüència (per a fer la seva visualització en la segona figura de la interfície)
        SOS_LP, SOS_BP, SOS_HP % Matius de coeficients de les seccions d'ordre 2 corresponents al disseny les 3 subbandes de l equalitzador (disseny original).
        G_LP, G_BP, G_HP % Vectors de guanys de les seccions d'ordre 2 corresponents al disseny les 3 subbandes de l equalitzador (disseny original).
        G_LPF, G_BPF, G_HPF % Valor dels guanys lineals que apliquem a les 3 subbandes de l equalitzador (s’hauran de convertir de dB a valors lineals).
        heq_length % longitud en mostres de la resposta impulsional de l'equalitzador.
        zieq % registre de condicions inicials/finals del filtre equalitzador.
        zirev % registre de condicions inicials/finals del filtre reverberador.
        h_reverb % resposta impulsional del filtre reverberador.
        h_l, h_b, h_h
        h_equalizer
        h_eq_most
    end
        
    methods (Access = private)
        
        % Funció que s'encarrega d'actualitzar el valor de les variables
        % globals G_LPF, G_BPF, G_HPF, i h_reverb a partir dels valors que
        % l'usuari ha modificat en els components de la interfície.
        function getCoeficients(app)
            app.G_LPF = app.LPFGaindBKnob.Value;
            app.G_BPF = app.BPFGaindBKnob.Value;
            app.G_HPF = app.HPFGaindBKnob.Value;


            T_reverb = app.ReverbTimeKnob.Value;    
            a_reverb = 10^(-6/T_reverb); %s'extreu de la formula
            Lh = round(T_reverb*app.fs);
            app.h_reverb = (a_reverb.^((0:(Lh-1))/app.fs)).*randn(1,Lh);
            app.h_reverb = app.h_reverb/norm(app.h_reverb);

            N = 500;
            x = [1;zeros(N-1,1)];
            % Generar els 3 filtres amb el guany rebut per parametre normalitzat
            SOSLowPass = app.SOS_LP;
            SOSBandPass = app.SOS_BP;
            SOSHighPass = app.SOS_HP;

            SOSLowPass(1,1:3) = app.SOS_LP(1,1:3)*db2mag(app.G_LPF)*prod(app.G_LP);
            SOSBandPass(1,1:3) = app.SOS_BP(1,1:3)*db2mag(app.G_BPF)*prod(app.G_BP);
            SOSHighPass(1,1:3) = app.SOS_HP(1,1:3)*db2mag(app.G_HPF)*prod(app.G_HP);
            % Calcular la resposta impulsional de cada filtre
            app.h_l = sosfilt(SOSLowPass,x);
            app.h_b = sosfilt(SOSBandPass,x);
            app.h_h = sosfilt(SOSHighPass,x);
    
            app.h_equalizer = app.h_l + app.h_b + app.h_h;
            app.h_eq_most = app.h_equalizer;
            heq = app.h_equalizer(1:30);    
            app.h_equalizer = heq;
            app.heq_length = length(app.h_equalizer);
        end
        
        % Funció que s'encarrega de visualitzar en temps o en freqüència el
        % senyal filtrat o original per cada trama d'àudio.
        function freq_temp(app)
            switch(app.Freq_Time.Value)
                case "Freq"
                    [app.audiof, f] = freqz(app.audioEfect, 1, length(app.audioEfect), app.fs);
                    app.audiof = app.audiof * 0.7 + 0.3 * abs(app.audiof(1:length(app.audiof))).^2;
                    plot(app.UI2Axes, f, 20 * log10(abs(app.audiof)));
                    title(app.UI2Axes, 'Input signal spectrum', 'Color', 'white');
                    xlabel(app.UI2Axes, "Frequency (Hz)");
                    ylabel(app.UI2Axes, "Amplitude (dB)");
                    ylim(app.UI2Axes, 'padded');
                    xlim(app.UIAxes, 'tight');
                    
                case "Time"
                    cla(app.UI2Axes);
                    app.UI2Axes.XScale = 'linear';
                    t = (0:length(app.audioEfect) - 1) / app.fs;
                    plot(app.UI2Axes, t, app.audioEfect);
                    title(app.UI2Axes, 'Time signal', 'Color', 'white');
                    xlabel(app.UI2Axes, "Time (s)");
                    ylabel(app.UI2Axes, "Amplitude");
                    xlim(app.UIAxes, 'tight');
                    ylim(app.UI2Axes, 'padded');
            end
        end

        function origi_filt(app)            
            switch(app.Origi_Filt.Value)
                case "Origi"
                    app.audioEfect = app.audio;
                case "Filt"
                    switch(app.EffectDropDown.Value)
                        case "Equalizer"
                            [app.audioEfect, app.zieq] = filter(app.h_equalizer,1, app.audio, app.zieq);
                            
                        case "Reverb"
                            audioIN = app.audio;
                            [app.audioEfect, app.zirev] = filter(app.h_reverb,1, audioIN, app.zirev);
                            app.audioEfect = audioIN * (1 - app.MixKnob.Value/100) +  app.audioEfect*(app.MixKnob.Value/100);

                    end    
            end
        end    
        
        % Funció que bloqueja o desbloqueja en funció del valor del boolean
        % flag a tots els botons i interruptors que permeten modificar el
        % filtre o gràfiques. Hi ha un atribut, per cada knob/switch, que
        % fa d'enable de cadascun dels controls de la interfície, l'atribut
        % és el següent, per exemple, knob.enable = true.
        function blockbuttons(app,flag)
            % Bloqueja/Desbloqueja els switches menys el on/off
            app.Freq_Time.Enable = flag;
            app.Origi_Filt.Enable = flag;
            % Bloqueja/Desbloqueja els knobs de equalizer i reverb
            app.ReverbTimeKnob.Enable = flag;
            app.MixKnob.Enable = flag;
            app.LPFGaindBKnob.Enable = flag;
            app.BPFGaindBKnob.Enable = flag;
            app.HPFGaindBKnob.Enable = flag;
            app.BufferLengthSlider.Enable = flag;
            app.EffectDropDown.Enable = flag;
        end

        % Funció que processa cada trama d'àudio que llegeix el fileReader
        % en funció del efecte seleccionat. Aquesta funció serà molt
        % semblant a la ja implementada a la Fase 1, però amb algunes
        % diferències, degut al fet que estem processant una trama d'un
        % flux continu d'àudio, i cal garantir la continuïtat del procés de
        % filtratge digital.
        function processAudio(app)
            % Actualitza les variables globals
            getCoeficients(app);
            % En funció de l'efecte a aplicar, es realitzarà un filtrat o
            % un altre
            origi_filt(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('filtersSOS.mat', 'SOSLowPass', 'SOSBandPass', 'SOSHighPass');
            load('filtersSOS.mat', 'GLowPass', 'GBandPass', 'GHighPass');
            app.SOS_LP = SOSLowPass;
            app.SOS_BP = SOSBandPass;
            app.SOS_HP = SOSHighPass;
            app.G_LP = GLowPass;
            app.G_BP = GBandPass;
            app.G_HP = GHighPass;
            app.fs = 44100;
            app.G_LPF = app.LPFGaindBKnob.Value;
            app.G_BPF = app.BPFGaindBKnob.Value;
            app.G_HPF = app.HPFGaindBKnob.Value;
            
            imageFile = 'Recursos/Imag.png';  % Ruta relativa desde el directorio actual
            img = imread(imageFile);
            app.Image.ImageSource = img;

            getCoeficients(app);
            grafica1(app);

        end

        % Value changed function: onoff
        function onoffValueChanged(app, event)
            if app.onoff.Value == "ON"
                 % Inicialitzem els buffers de la reverb i l'equalitzador
                 % per bandes
                 app.zirev = zeros(1,length(app.h_reverb)-1);
                 app.zieq = zeros(1,app.heq_length-1);
                 % Inicialitzem la comunicació amb els conversors AD i DA, i
                 % fixem la freqüència de mostratge a 44100 Hz
                 app.deviceReader = audioDeviceReader(app.fs, round(app.BufferLengthSlider.Value));
                 app.deviceWriter = audioDeviceWriter('SampleRate',round(app.deviceReader.SampleRate));
                 % Bloquejem tots els altres controls de la interfície menys
                 % l'onoff
                 app.blockbuttons(false);
                 % Inicializem comptador de temps
                 tic
                 try
                     while app.onoff.Value == "ON"
                         % Llegim una trama del conversor AD
                         app.audio = app.deviceReader();
                         % Processem una trama
                         processAudio(app);
                         % Escrivim la trama processada al conversor DA
                         app.deviceWriter(app.audioEfect);
                         % Dibuixem el senyal d'entrada/sortida en el domini
                         % temporal/frequencial
                         freq_temp(app);
                         % Parem comptador de temps
                         totalTime = toc;
                         tic
                         pause(0.001);
                         % Treiem missatge en la etiqueta de la interfície
                         % RTLabel sobre si el procés va a temps real o no
                         if (totalTime<(app.BufferLengthSlider.Value/app.fs))
                            app.RTlabel.Text = [' Real-Time Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs)];
                         else
                            app.RTlabel.Text = [ 'No Real-Time Frame length = ',num2str(app.BufferLengthSlider.Value/app.fs),' < Proc. Time =',num2str(totalTime),')'];
                         end
                     end
                 catch
                    app.RTlabel.Text = ('Real-time effect stopped');
                 end
                     % Desbloquejem tots els controls de la interfície
                     app.blockbuttons(true);
                     % Finalitzem la comunicació amb els conversors AD i DA
                     release(app.deviceReader);
                     release(app.deviceWriter);
            end
        end

        % Value changed function: BPFGaindBKnob, EffectDropDown, 
        % ...and 4 other components
        function grafica1(app, event)
            getCoeficients(app)
          
            switch(app.EffectDropDown.Value)
                    case "Equalizer"
                        nfft = 2048;
                        [H_L,f_l] = freqz(app.h_l,1,nfft,app.fs);
                        [H_B,f_b] = freqz(app.h_b,1,nfft,app.fs);
                        [H_H,f_h] = freqz(app.h_h,1,nfft,app.fs);
                        [H,f] = freqz(app.h_eq_most,1,nfft,app.fs);
                        
                        semilogx(app.UIAxes, f_l, 20*log10(abs(H_L)));
                        title(app.UIAxes, 'EQUALIZER Response', 'Color', 'white');
                        xlabel(app.UIAxes, "Frequency (Hz)");
                        ylabel(app.UIAxes, "Magnitude (dB)");
                        hold(app.UIAxes, 'on');
                        semilogx(app.UIAxes, f_b, 20*log10(abs(H_B)));
                        semilogx(app.UIAxes, f_h, 20*log10(abs(H_H)));
                        semilogx(app.UIAxes, f, 20*log10(abs(H)));
                        % Crear la layenda
                        hLegend = legend(app.UIAxes, 'Lowpass filter', 'Bandpass filter', 'Highpass filter', 'Equalizer', 'Location', 'northeast');
                        % Desactivar la interactividad de la leyenda
                        hLegend.Interpreter = 'none';
                        hLegend.PickableParts = 'none';
                        hold(app.UIAxes, 'off');
                        ylim (app.UIAxes, [-40,30]);
                        xlim (app.UIAxes,'tight');
                        grid(app.UIAxes, 'on');                        
                        
                    case "Reverb"
                        cla(app.UIAxes);
                        app.UIAxes.XScale = 'linear';
                        % Ocultar la leyenda en el caso "Reverb"
                        legend(app.UIAxes, 'off');
                        t_audioRev = (0:length(app.h_reverb)-1) / app.fs;
                        plot(app.UIAxes, t_audioRev, app.h_reverb);
                        title(app.UIAxes, 'Reverb impulse response', 'Color', 'white');
                        xlabel(app.UIAxes, "time");
                        ylabel(app.UIAxes, "amplitude");
                        xlim(app.UIAxes, 'tight');
                        ylim (app.UIAxes, [-0.07,0.07]);   
                        grid(app.UIAxes, 'on');
            end  
        end

        % Button down function: UI2Axes, UIAxes
        function UIAxesButtonDown(app, event)
            pan(app.UIAxes, 'off');
            zoom(app.UIAxes, 'off');
            pan(app.UI2Axes, 'off');
            zoom(app.UI2Axes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.laSalleEquaiozerUIFigure = uifigure('Visible', 'off');
            app.laSalleEquaiozerUIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.laSalleEquaiozerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.Text = 'Open';
            app.SavefileMenu = uimenu(app.FileMenu);
            app.SavefileMenu.Text = 'Save';
            app.GridLayout = uigridlayout(app.laSalleEquaiozerUIFigure);
            app.UI2Axes = uiaxes(app.GridLayout);
            ylabel(app.UI2Axes, 'Magnitude (dB)')
            app.UI2Axes.XTick = [];
            app.UI2Axes.XTickLabel = '';
            app.UI2Axes.YTick = [];
            app.UI2Axes.YTickLabel = '';
            app.UI2Axes.ZTick = [];
            app.UI2Axes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes = uiaxes(app.GridLayout);
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image = uiimage(app.GridLayout);
            app.Equalizer = uipanel(app.GridLayout);
            app.EqualizerLabel = uilabel(app.Equalizer);
            app.EqualizerLabel.Text = 'Equalizer';
            app.LPFGaindBKnobLabel = uilabel(app.Equalizer);
            app.LPFGaindBKnobLabel.Text = 'LPF Gain (dB)';
            app.BPFGaindBKnobLabel = uilabel(app.Equalizer);
            app.BPFGaindBKnobLabel.Text = 'BPF Gain (dB)';
            app.HPFGaindBKnobLabel = uilabel(app.Equalizer);
            app.HPFGaindBKnobLabel.Text = 'HPF Gain (dB)';
            app.LPFGaindBKnob = uiknob(app.Equalizer, 'continuous');
            app.LPFGaindBKnob.Limits = [-30 20];
            app.LPFGaindBKnob.MajorTicks = [-30 -20 -10 0 10 20];
            app.LPFGaindBKnob.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.LPFGaindBKnob.Value = -15;
            app.BPFGaindBKnob = uiknob(app.Equalizer, 'continuous');
            app.BPFGaindBKnob.Limits = [-30 20];
            app.BPFGaindBKnob.MajorTicks = [-30 -20 -10 0 10 20];
            app.BPFGaindBKnob.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.BPFGaindBKnob.Value = -15;
            app.HPFGaindBKnob = uiknob(app.Equalizer, 'continuous');
            app.HPFGaindBKnob.Limits = [-30 20];
            app.HPFGaindBKnob.MajorTicks = [-30 -20 -10 0 10 20];
            app.HPFGaindBKnob.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.HPFGaindBKnob.Value = -15;
            app.Reverb = uipanel(app.GridLayout);
            app.ReverbLabel = uilabel(app.Reverb);
            app.ReverbLabel.Text = 'Reverb';
            app.Label = uilabel(app.Reverb);
            app.Label.Text = 'Reverb.Time';
            app.MixLabel = uilabel(app.Reverb);
            app.MixLabel.Text = '% Mix';
            app.ReverbTimeKnob = uiknob(app.Reverb, 'continuous');
            app.ReverbTimeKnob.Limits = [0.5 5];
            app.ReverbTimeKnob.MajorTicks = [0.5 1.5 2.5 3.5 4.5 5];
            app.ReverbTimeKnob.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.ReverbTimeKnob.Value = 1;
            app.MixKnob = uiknob(app.Reverb, 'continuous');
            app.MixKnob.MajorTicks = [0 20 40 60 80 100];
            app.MixKnob.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.MixKnob.Value = 50;
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.EffectDropDown = uidropdown(app.ControlPanel);
            app.EffectDropDown.Items = {'Equalizer', 'Reverb'};
            app.EffectDropDown.ValueChangedFcn = createCallbackFcn(app, @grafica1, true);
            app.EffectDropDown.Value = 'Equalizer';
            app.ActiveEffectsLabel = uilabel(app.ControlPanel);
            app.ActiveEffectsLabel.Text = 'Active Effects';
            app.Freq_Time = uiswitch(app.ControlPanel, 'slider');
            app.Freq_Time.Items = {'Freq', 'Time'};
            app.Freq_Time.Value = 'Freq';
            app.Origi_Filt = uiswitch(app.ControlPanel, 'slider');
            app.Origi_Filt.Items = {'Origi', 'Filt'};
            app.Origi_Filt.Value = 'Origi';
            app.onoff = uiswitch(app.ControlPanel, 'slider');
            app.onoff.Items = {'ON', 'OFF'};
            app.onoff.ValueChangedFcn = createCallbackFcn(app, @onoffValueChanged, true);
            app.onoff.Value = 'OFF';
            app.BufferLengthSliderLabel = uilabel(app.GridLayout);
            app.BufferLengthSliderLabel.Text = 'Buffer Length';
            app.BufferLengthSlider = uislider(app.GridLayout);
            app.BufferLengthSlider.Limits = [1024 10240];
            app.BufferLengthSlider.MajorTicks = [];
            app.BufferLengthSlider.Value = 1024;
            app.RTlabel = uilabel(app.GridLayout);
            app.RTlabel.Text = '';
            % Show the figure after all components are created
            app.laSalleEquaiozerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsAudioTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.laSalleEquaiozerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.laSalleEquaiozerUIFigure)
        end
    end
end